classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DistanciasDropDown           matlab.ui.control.DropDown
        DistanciasDropDownLabel      matlab.ui.control.Label
        ObtenercaracteristicasLabel  matlab.ui.control.Label
        ClasificarButton             matlab.ui.control.Button
        SeleccionarimagenaclasificarLabel  matlab.ui.control.Label
        SeleccionarButton            matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        MatrizdeconfucinButton       matlab.ui.control.Button
        ImagenactualLabel            matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        path % Description
        flag
        file
        img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.jpg');
            app.flag = 1;
            concatenado = strcat(app.path, app.file);
            app.img = imread(concatenado);
            app.Image.ImageSource = app.img;
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            if app.flag == 1
                canal_rojo = app.img(:, :, 1);
                canal_verde = app.img(:, :, 2);
                canal_azul = app.img(:, :, 3);
    
                lista_rojo = reshape(canal_rojo, [], 1);
                lista_verde = reshape(canal_verde, [], 1);
                lista_azul = reshape(canal_azul, [], 1);
                lista = [lista_rojo lista_verde lista_azul];

                S = sum(lista, 2);
                S = S/3;
                
                sumaR = cast(0, 'int32');
                sumaG = cast(0, 'int32');
                sumaB = cast(0, 'int32');
                len = cast(0, 'int32');
                for i = 1:10000
                    if S(i) < 246
                        len = len + 1;
                        sumaR = sumaR + cast(lista_rojo(i), 'int32');
                        sumaG = sumaG + cast(lista_verde(i), 'int32');
                        sumaB = sumaB + cast(lista_azul(i), 'int32');
                    end
                end

                sumaR = sumaR/len;
                sumaG = sumaG/len;
                sumaB = sumaB/len;
                centroides = [  250.68 231.16 148.72 6313.0731 418.6696 0.4586;
                                216.24 166.2 112.07 4564.1159 295.6127 0.6563;
                                121.23 132.26 112.56 6656.3293 310.8306 0.8657;
                                149.91 137.27 187.62 6873.5731 321.9726 0.8319;
                                114.05 47.72 48.59 5521.1524 329.4655 0.6428];

                dist = zeros(5,1);

                imagen = im2double(app.img);
                umbral_blanco = 0.9;
                mascara_blanco = imagen > umbral_blanco;
                factor_oscurecimiento = 0.05;
                imagen_oscurecida = imagen;
                imagen_oscurecida(~mascara_blanco) = imagen_oscurecida(~mascara_blanco) * factor_oscurecimiento;
                imagen_oscurecida = min(max(imagen_oscurecida, 0), 1);
                imagen_gris = rgb2gray(imagen_oscurecida);
                imagen_not = imcomplement(imagen_gris);
                im_filtrada = wiener2(imagen_not,[10 10]);
                %Binarizamos la imagen
                im_bin = imbinarize(im_filtrada);
                 % dilatamos la imagen
                [m,n] = size(im_bin);
                im_bin2=imbinarize(zeros(m,n));
                for i=1:m
                    for j=1:n
                        if(im_bin(i,j) == 1)
                           im_bin2(i:i+2,j:j+2) = 1;  
                        end
                    end
                end
                im_bin = im_bin2;
                [Bordes, Objetos] = bwboundaries(im_bin,'noholes');
                stats = regionprops(Objetos, 'Area');
            
                borde=Bordes{1};
                delta_sq = diff(borde).^2;
                area = stats(1).Area;
                perimetro = sum(sqrt(sum(delta_sq,2))) ;
                metrica = 4*pi*area/perimetro^2;

                for i = 1:5
                    a = (double(sumaR) - double(centroides(i,1)))^2;
                    b = (double(sumaG) - double(centroides(i,2)))^2;
                    c = (double(sumaB) - double(centroides(i,3)))^2;
                    d = (double(area) - double(centroides(i,4)))^2;
                    e = (double(perimetro) - double(centroides(i,5)))^2;
                    f = (double(metrica) - double(centroides(i,6)))^2;
                    medio = a + b + c + d + e + f;
                    dist(i) = sqrt(double(medio));
                end
                minimo = min(dist);

                if dist(1) == minimo
                    texto = 'Cantaloupe';
                elseif dist(2) == minimo
                    texto = 'Grandilla';
                elseif dist(3) == minimo
                    texto = 'Mango';
                elseif dist(4) == minimo
                    texto = 'Raspberry';
                elseif dist(5) == minimo
                    texto = 'Strawberry';
                end
                app.ImagenactualLabel.Text = texto;
            else
                disp('Primero seleccione la imagen')
            end
        end

        % Button pushed function: MatrizdeconfucinButton
        function MatrizdeconfucinButtonPushed(app, event)
            matriz = zeros(5,5);
            disp('[ Generando matriz de confusion ]');
            folders = ["./fruits/Cantaloupe/" "./fruits/Granadilla/" "./fruits/Mango/" "./fruits/Raspberry/" "./fruits/Strawberry/"];
            for ijk = 1:5
                for k = 1:164
                    union = strcat(num2str(k), '.jpg');
                    app.path = strcat(folders(ijk), union);
                    app.img = imread(app.path);
                    canal_rojo = app.img(:, :, 1);
                    canal_verde = app.img(:, :, 2);
                    canal_azul = app.img(:, :, 3);

                    lista_rojo = reshape(canal_rojo, [], 1);
                    lista_verde = reshape(canal_verde, [], 1);
                    lista_azul = reshape(canal_azul, [], 1);
                    lista = [lista_rojo lista_verde lista_azul];

                    S = sum(lista, 2);
                    S = S/3;

                    sumaR = cast(0, 'int32');
                    sumaG = cast(0, 'int32');
                    sumaB = cast(0, 'int32');
                    len = cast(0, 'int32');

                    for i = 1:10000
                        if S(i) < 246
                            len = len + 1;
                            sumaR = sumaR + cast(lista_rojo(i), 'int32');
                            sumaG = sumaG + cast(lista_verde(i), 'int32');
                            sumaB = sumaB + cast(lista_azul(i), 'int32');
                        end
                    end
        
                    sumaR = sumaR/len;
                    sumaG = sumaG/len;
                    sumaB = sumaB/len;

                    centroides = [  250.68 231.16 148.72 6313.0731 418.6696 0.4586;
                                    216.24 166.2 112.07 4564.1159 295.6127 0.6563;
                                    121.23 132.26 112.56 6656.3293 310.8306 0.8657;
                                    149.91 137.27 187.62 6873.5731 321.9726 0.8319;
                                    114.05 47.72 48.59 5521.1524 329.4655 0.6428];
        
                    dist = zeros(5,1);
        
                    imagen = im2double(app.img);
                    umbral_blanco = 0.9;
                    mascara_blanco = imagen > umbral_blanco;
                    factor_oscurecimiento = 0.05;
                    imagen_oscurecida = imagen;
                    imagen_oscurecida(~mascara_blanco) = imagen_oscurecida(~mascara_blanco) * factor_oscurecimiento;
                    imagen_oscurecida = min(max(imagen_oscurecida, 0), 1);
                    imagen_gris = rgb2gray(imagen_oscurecida);
                    imagen_not = imcomplement(imagen_gris);
                    im_filtrada = wiener2(imagen_not,[10 10]);
                    %Binarizamos la imagen
                    im_bin = imbinarize(im_filtrada);
                     % dilatamos la imagen
                    [m,n] = size(im_bin);
                    im_bin2=imbinarize(zeros(m,n));
                    for i=1:m
                        for j=1:n
                            if(im_bin(i,j) == 1)
                               im_bin2(i:i+2,j:j+2) = 1;  
                            end
                        end
                    end
                    im_bin = im_bin2;
                    [Bordes, Objetos] = bwboundaries(im_bin,'noholes');

                    stats = regionprops(Objetos, 'Area');
                    borde=Bordes{1};
                    delta_sq = diff(borde).^2;
                    area = stats(1).Area;
                    perimetro = sum(sqrt(sum(delta_sq,2))) ;
                    metrica = 4*pi*area/perimetro^2;
        
                    for i = 1:5
                        a = (double(sumaR) - double(centroides(i,1)))^2;
                        b = (double(sumaG) - double(centroides(i,2)))^2;
                        c = (double(sumaB) - double(centroides(i,3)))^2;
                        d = (double(area) - double(centroides(i,4)))^2;
                        e = (double(perimetro) - double(centroides(i,5)))^2;
                        f = (double(metrica) - double(centroides(i,6)))^2;
                        medio = a + b + c + d + e + f;
                        dist(i) = sqrt(double(medio));
                    end
                    minimo = min(dist);

                    if dist(1) == minimo
                        pred = 1;
                    elseif dist(2) == minimo
                        pred = 2;
                    elseif dist(3) == minimo
                        pred = 3;
                    elseif dist(4) == minimo
                        pred = 4;
                    elseif dist(5) == minimo
                        pred = 5;
                    end
                    matriz(ijk, pred) = matriz(ijk, pred) + 1;
                end
                fprintf('[ Clase %d terminada ]\n', ijk);
            end
            disp('[ Generado finalizado ]')
            disp(matriz)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {384, 384};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {296, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SeleccionarButton = uibutton(app.LeftPanel, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.SeleccionarimagenaclasificarLabel = uilabel(app.LeftPanel);
            app.SeleccionarimagenaclasificarLabel.Text = 'Seleccionar imagen a clasificar:';
            app.ClasificarButton = uibutton(app.LeftPanel, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.ObtenercaracteristicasLabel = uilabel(app.LeftPanel);
            app.ObtenercaracteristicasLabel.Text = 'Obtener caracteristicas:';
            app.DistanciasDropDownLabel = uilabel(app.LeftPanel);
            app.DistanciasDropDownLabel.Text = 'Distancias:';
            app.DistanciasDropDown = uidropdown(app.LeftPanel);
            app.DistanciasDropDown.Items = {'Euclidiana', 'Manhatan', 'Mahalanobis'};
            app.DistanciasDropDown.Value = 'Euclidiana';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.ImagenactualLabel = uilabel(app.RightPanel);
            app.ImagenactualLabel.Text = 'Imagen actual';
            app.MatrizdeconfucinButton = uibutton(app.RightPanel, 'push');
            app.MatrizdeconfucinButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizdeconfucinButtonPushed, true);
            app.MatrizdeconfucinButton.Text = 'Matriz de confución';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end